classdef SSATFv2_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AfterburnerTurbofan            matlab.ui.Figure
        FlightConditionsPanel          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        T_0KEditFieldLabel             matlab.ui.control.Label
        T_0KEditField                  matlab.ui.control.NumericEditField
        MachNumberEditFieldLabel       matlab.ui.control.Label
        MachNumberMinEditField         matlab.ui.control.NumericEditField
        P_0P_9EditFieldLabel           matlab.ui.control.Label
        P_0P_9EditField                matlab.ui.control.NumericEditField
        P_0P_19EditFieldLabel          matlab.ui.control.Label
        P_0P_19EditField               matlab.ui.control.NumericEditField
        gamma_cEditFieldLabel          matlab.ui.control.Label
        gamma_cEditField               matlab.ui.control.NumericEditField
        c_pckJkgKLabel                 matlab.ui.control.Label
        c_pckJkgKEditField             matlab.ui.control.NumericEditField
        MaxMachNumberEditFieldLabel    matlab.ui.control.Label
        MaxMachNumberEditField         matlab.ui.control.NumericEditField
        MachNoIncrementEditFieldLabel  matlab.ui.control.Label
        MachNoIncrementEditField       matlab.ui.control.NumericEditField
        TabGroup                       matlab.ui.container.TabGroup
        Table                          matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        Fm_0dotTab                     matlab.ui.container.Tab
        FovermdotPlot                  matlab.ui.control.UIAxes
        STab                           matlab.ui.container.Tab
        SPlot                          matlab.ui.control.UIAxes
        FuelAirRatioTab                matlab.ui.container.Tab
        FuelPlot                       matlab.ui.control.UIAxes
        Eta_cTab                       matlab.ui.container.Tab
        eta_cPlot                      matlab.ui.control.UIAxes
        Eta_tTab                       matlab.ui.container.Tab
        eta_tPlot                      matlab.ui.control.UIAxes
        PropulsiveEfficiencyTab        matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        eta_pPlot                      matlab.ui.control.UIAxes
        TermalEfficiencyTab            matlab.ui.container.Tab
        eta_thermoPlot                 matlab.ui.control.UIAxes
        OverallEfficiencyTab           matlab.ui.container.Tab
        eta_oPlot                      matlab.ui.control.UIAxes
        DesignConstraintsPanel         matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        MinT_t4KLabel                  matlab.ui.control.Label
        MinT_t4KEditField              matlab.ui.control.NumericEditField
        MinT_t17KLabel                 matlab.ui.control.Label
        MinT_t17KEditField             matlab.ui.control.NumericEditField
        MinT_t7KLabel                  matlab.ui.control.Label
        MinT_t7KEditField              matlab.ui.control.NumericEditField
        T_t4IncrementEditFieldLabel    matlab.ui.control.Label
        T_t4IncrementEditField         matlab.ui.control.NumericEditField
        MaxT_t4KEditFieldLabel         matlab.ui.control.Label
        MaxT_t4KEditField              matlab.ui.control.NumericEditField
        T_t7IncrementEditFieldLabel    matlab.ui.control.Label
        T_t7IncrementEditField         matlab.ui.control.NumericEditField
        MaxT_t7KEditFieldLabel         matlab.ui.control.Label
        MaxT_t7KEditField              matlab.ui.control.NumericEditField
        T_t17IncrementEditFieldLabel   matlab.ui.control.Label
        T_t17IncrementEditField        matlab.ui.control.NumericEditField
        MaxT_t17KEditFieldLabel        matlab.ui.control.Label
        MaxT_t17KEditField             matlab.ui.control.NumericEditField
        ComponentPerformancesPanel     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        pi_bEditFieldLabel             matlab.ui.control.Label
        pi_bEditField                  matlab.ui.control.NumericEditField
        eta_ABEditFieldLabel           matlab.ui.control.Label
        eta_ABEditField                matlab.ui.control.NumericEditField
        eta_DBEditFieldLabel           matlab.ui.control.Label
        eta_DBEditField                matlab.ui.control.NumericEditField
        eta_mEditFieldLabel            matlab.ui.control.Label
        eta_mEditField                 matlab.ui.control.NumericEditField
        eta_bEditFieldLabel            matlab.ui.control.Label
        eta_bEditField                 matlab.ui.control.NumericEditField
        pi_DBEditFieldLabel            matlab.ui.control.Label
        pi_DBEditField                 matlab.ui.control.NumericEditField
        pi_d_maxEditFieldLabel         matlab.ui.control.Label
        pi_d_maxEditField              matlab.ui.control.NumericEditField
        e_cEditFieldLabel              matlab.ui.control.Label
        e_cEditField                   matlab.ui.control.NumericEditField
        e_tEditFieldLabel              matlab.ui.control.Label
        e_tEditField                   matlab.ui.control.NumericEditField
        e_fEditFieldLabel              matlab.ui.control.Label
        e_fEditField                   matlab.ui.control.NumericEditField
        pi_fnEditFieldLabel            matlab.ui.control.Label
        pi_fnEditField                 matlab.ui.control.NumericEditField
        pi_nEditFieldLabel             matlab.ui.control.Label
        pi_nEditField                  matlab.ui.control.NumericEditField
        pi_ABEditFieldLabel            matlab.ui.control.Label
        pi_ABEditField                 matlab.ui.control.NumericEditField
        CaseSelectionPanel             matlab.ui.container.Panel
        CaseDropDownLabel              matlab.ui.control.Label
        CaseDropDown                   matlab.ui.control.DropDown
        DesignChoicesPanel             matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ComputeButton                  matlab.ui.control.Button
        Minpi_cValueLabel              matlab.ui.control.Label
        Minpi_cValueEditField          matlab.ui.control.NumericEditField
        Minpi_fValueEditFieldLabel     matlab.ui.control.Label
        Minpi_fEditField               matlab.ui.control.NumericEditField
        Maxpi_fValueEditFieldLabel     matlab.ui.control.Label
        Maxpi_fEditField               matlab.ui.control.NumericEditField
        MinByPassRatioValueEditFieldLabel  matlab.ui.control.Label
        MinByPassRatioEditField        matlab.ui.control.NumericEditField
        MaxByPassRatioEditFieldLabel   matlab.ui.control.Label
        MaxByPassRatioEditField        matlab.ui.control.NumericEditField
        pi_fIncrementEditFieldLabel    matlab.ui.control.Label
        pi_fIncrementEditField         matlab.ui.control.NumericEditField
        ByPassRatioIncrementEditFieldLabel  matlab.ui.control.Label
        ByPassRatioIncrementEditField  matlab.ui.control.NumericEditField
        pi_cIncrementEditField_2Label  matlab.ui.control.Label
        pi_cIncrementEditField         matlab.ui.control.NumericEditField
        Maxpi_cValueEditField_2Label   matlab.ui.control.Label
        Maxpi_cValueEditField          matlab.ui.control.NumericEditField
        XAxisSwitchLabel               matlab.ui.control.Label
        XAxisSwitch                    matlab.ui.control.Switch
        GasPropertiesPanel             matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        c_ptkJkgKEditFieldLabel        matlab.ui.control.Label
        c_ptkJkgKEditField             matlab.ui.control.NumericEditField
        c_pABkJkgKLabel                matlab.ui.control.Label
        c_pABkJkgKEditField            matlab.ui.control.NumericEditField
        c_pDBkJkgKEditFieldLabel       matlab.ui.control.Label
        c_pDBkJkgKEditField            matlab.ui.control.NumericEditField
        gamma_ABEditFieldLabel         matlab.ui.control.Label
        gamma_ABEditField              matlab.ui.control.NumericEditField
        gamma_tEditFieldLabel          matlab.ui.control.Label
        gamma_tEditField               matlab.ui.control.NumericEditField
        gamma_DBEditFieldLabel         matlab.ui.control.Label
        gamma_DBEditField              matlab.ui.control.NumericEditField
        FuelPropertiesPanel            matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        h_PRkJkgEditFieldLabel         matlab.ui.control.Label
        h_PRkJkgEditField              matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function [F__mdot_0,f,f_AB,f_DB,S,eta_c,eta_t,eta_f,eta_P,eta_T,eta_O] = ...
                ATFwithSeperateExhausts(app,M_0,T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                T_t4,T_t7,T_t17,pi_c,pi_f,alpha)
            
            % Calculations
            g_c = 1;
            R_c = ((gamma_c-1)./gamma_c).*c_pc; %
            R_t = ((gamma_t-1)./gamma_t).*c_pt; %
            R_AB = ((gamma_AB-1)./gamma_AB).*c_p_AB; %
            R_DB = ((gamma_DB-1)./gamma_DB).*c_p_DB; %
            a_0 = sqrt(gamma_c.*R_c.*g_c.*T_0*1000); % kJ den Joule'e gecis yapildi
            V_0 = a_0.*M_0;
            tau_r = 1 + (gamma_c-1)./2.*M_0.^2;
            pi_r = tau_r.^(gamma_c./(gamma_c-1));
            if M_0 <= 1
                eta_r = 1;
            else
                eta_r = 1 - 0.075*(M_0-1).^(1.35);
            end
            pi_d = pi_d_max.*eta_r;
            tau_lambda = (c_pt.*T_t4)./(c_pc.*T_0);
            tau_lambda_AB = (c_p_AB.*T_t7)./(c_pc.*T_0);
            tau_lambda_DB = (c_p_DB.*T_t17)./(c_pc.*T_0);
            tau_c = pi_c.^((gamma_c-1)./(gamma_c.*e_c));
            eta_c = (pi_c.^((gamma_c-1)./gamma_c)-1)./(tau_c-1);
            tau_f = pi_f.^((gamma_c-1)./(gamma_c.*e_f));
            eta_f = (pi_f.^((gamma_c-1)./gamma_c)-1)./(tau_f-1); %%%
            f = (tau_lambda-tau_r.*tau_c)./(eta_b.*h_PR./(c_pc.*T_0)-tau_lambda);
            tau_t = 1 - 1./(eta_m.*(1+f)).*(tau_r./tau_lambda).*(tau_c-1+alpha.*(tau_f-1));
            pi_t = tau_t.^(gamma_t./((gamma_t-1).*e_t));
            eta_t = (1-tau_t)./(1-tau_t.^(1./e_t));
            P_t9__P_9 = P_0__P_9.*pi_r.*pi_d.*pi_c.*pi_b.*pi_t.*pi_AB.*pi_n;
            M_9 = sqrt(2./(gamma_AB-1) .* (P_t9__P_9.^((gamma_AB-1)./gamma_AB) - 1));
            T_9__T_0 = T_t7./T_0./(P_t9__P_9.^((gamma_AB-1)./gamma_AB));
            V_9__a_0 = M_9.*sqrt(gamma_AB.*R_AB./gamma_c./R_c.*T_9__T_0);
            P_t19__P_19 = P_0__P_19.*pi_r.*pi_d.*pi_f.*pi_DB.*pi_fn;
            M_19 = sqrt(2./(gamma_DB-1) .* (P_t19__P_19.^((gamma_DB-1)./gamma_DB) - 1));
            T_19__T_0 = T_t17./T_0./(P_t19__P_19.^((gamma_DB-1)./gamma_DB));
            V_19__a_0 = M_19.*sqrt(gamma_DB.*R_DB./gamma_c./R_c.*T_19__T_0);
            f_AB = (1+f).*(tau_lambda_AB-tau_lambda.*tau_t)./(eta_AB.*h_PR./(c_pc*T_0)-tau_lambda_AB);
            f_DB = (tau_lambda_DB-tau_r.*tau_f)./(eta_DB.*h_PR./(c_pc*T_0)-tau_lambda_DB);
            F__mdot_0 = 1./(1+alpha).*(a_0/g_c).*((1+f+f_AB).*V_9__a_0-M_0+(1+f+f_AB).*...
                R_AB./R_c.*(T_9__T_0)./(V_9__a_0).*(1-P_0__P_9)./(gamma_c))...
                +alpha./(1+alpha).*(a_0/g_c).*((1+f_DB).*V_19__a_0-M_0+(1+f_DB).*...
                R_DB./R_c.*(T_19__T_0)./(V_19__a_0).*(1-P_0__P_19)./(gamma_c));
            S = 10^6*(f+f_AB+alpha*f_DB)./(1+alpha)./(F__mdot_0);
            F__mdot_0 = F__mdot_0*0.224809/2.20462;
            S = S/0.2248089*3600*2.20462*10^(-6);
            eta_P = 2.*M_0.*((1+f+f_AB).*V_9__a_0+alpha.*(1+f_DB).*V_19__a_0-(1+alpha).*M_0)./...
                ((1+f+f_AB).*V_9__a_0.^2+alpha.*(1+f_DB).*V_19__a_0.^2-(1+alpha).*M_0.^2);
            eta_T = (a_0.^2).*((1+f+f_AB).*V_9__a_0.^2+alpha.*(1+f_DB).*V_19__a_0.^2-(1+alpha).*M_0.^2)./...
                (2.*g_c.*(f+f_AB+f_DB).*h_PR.*1000);
            eta_O = eta_T.*eta_P;
            if not(isreal(F__mdot_0)) && not(isreal(S))
                F__mdot_0 = NaN;
                f = NaN;
                f_AB = NaN;
                f_DB = NaN;
                S = NaN;
                eta_c = NaN;
                eta_t = NaN;
                eta_f = NaN;
                eta_P = NaN;
                eta_T = NaN;
                eta_O = NaN;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.MachNoIncrementEditField,'enable','off');
            set(app.MaxMachNumberEditField,'enable','off');
            set(app.pi_cIncrementEditField,'enable','off');
            set(app.Maxpi_cValueEditField,'enable','off');
            set(app.pi_fIncrementEditField,'enable','off');
            set(app.Maxpi_fEditField,'enable','off');
            set(app.ByPassRatioIncrementEditField,'enable','off');
            set(app.MaxByPassRatioEditField,'enable','off');
            set(app.T_t4IncrementEditField,'enable','off');
            set(app.MaxT_t4KEditField,'enable','off');
            set(app.T_t7IncrementEditField,'enable','off');
            set(app.MaxT_t7KEditField,'enable','off');
            set(app.T_t17IncrementEditField,'enable','off');
            set(app.MaxT_t17KEditField,'enable','off');
            app.Minpi_fValueEditFieldLabel.Text = 'pi_f';
            app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
            app.Minpi_cValueLabel.Text = 'pi_c';
            app.MinT_t4KLabel.Text = 'T_t4 (K)';
            app.MinT_t7KLabel.Text = 'T_t7 (K)';
            app.MinT_t17KLabel.Text = 'T_t17 (K)';
            set(app.XAxisSwitch,'enable','off');
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            solverval = app.CaseDropDown.Value;
            xaxisval = app.XAxisSwitch.Value;
            % Resetting Graphs
            cla(app.FovermdotPlot,'reset');
            cla(app.SPlot,'reset');
            cla(app.FuelPlot,'reset');
            cla(app.eta_cPlot,'reset');
            cla(app.eta_tPlot,'reset');
            cla(app.eta_pPlot,"reset");
            cla(app.eta_thermoPlot,"reset");
            cla(app.eta_oPlot,"reset");
            
            % Taking Edit Field Value
            M_0_min = app.MachNumberMinEditField.Value;
            M_0_increment = app.MachNoIncrementEditField.Value;
            M_0_max = app.MaxMachNumberEditField.Value;
            T_0 = app.T_0KEditField.Value;
            gamma_c = app.gamma_cEditField.Value;
            c_pc = app.c_pckJkgKEditField.Value;
            gamma_t = app.gamma_tEditField.Value;
            c_pt = app.c_ptkJkgKEditField.Value;
            h_PR = app.h_PRkJkgEditField.Value;
            gamma_AB = app.gamma_ABEditField.Value;
            c_p_AB = app.c_pABkJkgKEditField.Value;
            gamma_DB = app.gamma_DBEditField.Value;
            c_p_DB = app.c_pDBkJkgKEditField.Value;
            pi_d_max = app.pi_d_maxEditField.Value;
            pi_b = app.pi_bEditField.Value;
            pi_AB = app.pi_ABEditField.Value;
            pi_DB = app.pi_DBEditField.Value;
            pi_n = app.pi_nEditField.Value;
            pi_fn = app.pi_fnEditField.Value;
            e_c = app.e_cEditField.Value;
            e_f = app.e_fEditField.Value;
            e_t = app.e_tEditField.Value;
            eta_b = app.eta_bEditField.Value;
            eta_AB = app.eta_ABEditField.Value;
            eta_DB = app.eta_DBEditField.Value;
            eta_m = app.eta_mEditField.Value;
            P_0__P_9 = app.P_0P_9EditField.Value;
            P_0__P_19 = app.P_0P_19EditField.Value;
            
            T_t4_min = app.MinT_t4KEditField.Value;
            T_t4_increment = app.T_t4IncrementEditField.Value;
            T_t4_max = app.MaxT_t4KEditField.Value;
            
            T_t7_min = app.MinT_t7KEditField.Value;
            T_t7_increment = app.T_t7IncrementEditField.Value;
            T_t7_max = app.MaxT_t7KEditField.Value;
            
            T_t17_min = app.MinT_t17KEditField.Value;
            T_t17_increment = app.T_t17IncrementEditField.Value;
            T_t17_max = app.MaxT_t17KEditField.Value;
            
            alpha_min = app.MinByPassRatioEditField.Value;
            alpha_max = app.MaxByPassRatioEditField.Value;
            alpha_increment = app.ByPassRatioIncrementEditField.Value;
            pi_f_min = app.Minpi_fEditField.Value;
            pi_f_increment = app.pi_fIncrementEditField.Value;
            pi_f_max = app.Maxpi_fEditField.Value;
            pi_c_min = app.Minpi_cValueEditField.Value;
            pi_c_increment = app.pi_cIncrementEditField.Value;
            pi_c_max = app.Maxpi_cValueEditField.Value;


            switch solverval
                case 'Single Condition'
                    M_0 = M_0_min;
                    pi_c = pi_c_min;
                    pi_f = pi_f_min;
                    alpha = alpha_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    
                    [F__mdot_0,f,f_AB,f_DB,S,eta_c,eta_t,eta_f,eta_P,eta_T,eta_O] = ...
                        ATFwithSeperateExhausts(app,M_0,T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                        gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                        pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                        T_t4,T_t7,T_t17,pi_c,pi_f,alpha);
                    
                    % Creating Table
                    t = table(M_0,pi_c,pi_f,alpha,T_t4,T_t7,T_t17,F__mdot_0,f,f_AB,f_DB,S,eta_c,eta_t,eta_f,eta_P,eta_T,eta_O);
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                case 'Varying Bypass and pi_f'
                    M_0 = M_0_min;
                    pi_c = pi_c_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    pi_f = pi_f_min:pi_f_increment:pi_f_max;
                    alpha = alpha_min:alpha_increment:alpha_max;
                    tablo = zeros(length(pi_f)*length(alpha),18);
                    
                    
                    for i = 1:length(pi_f)
                        for j=1:length(alpha)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0,T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7,T_t17,pi_c,pi_f(i),alpha(j));
                            
                            tablo(length(alpha)*(i-1)+j,:) = ...
                                [M_0,pi_c,pi_f(i),alpha(j),T_t4,T_t7,T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    % Handling Plots
                    switch xaxisval
                        case 'pi_f'
                            
                            plot(app.FovermdotPlot,pi_f,F__mdot_0(:,:),"LineWidth",1.4)
                            title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0} Graph$","Interpreter","Latex")
                            grid(app.FovermdotPlot,'minor')
                            xlabel(app.FovermdotPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                            legend(app.FovermdotPlot,'\alpha='+string(alpha),'Location','southeast','NumColumns',2);
                            
                            plot(app.SPlot,pi_f,S(:,:),"LineWidth",1.4)
                            title(app.SPlot,"S $Graph$","Interpreter","Latex")
                            grid(app.SPlot,'minor')
                            xlabel(app.SPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                            legend(app.SPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                            
                            plot(app.FuelPlot,pi_f,f(:,1),pi_f,f_AB(:,:),pi_f,f_DB(:,1),"LineWidth",1.4);
                            title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                            grid(app.FuelPlot,'minor')
                            xlabel(app.FuelPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                            leg = ['f for all \alpha','f_AB for \alpha='+string(alpha),'f_DB for all \alpha'];
                            legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_cPlot,pi_f,eta_c(:,:),"LineWidth",1.4)
                            title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                            grid(app.eta_cPlot,'minor')
                            xlabel(app.eta_cPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                            legend(app.eta_cPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_tPlot,pi_f,eta_t(:,:),"LineWidth",1.4)
                            title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                            grid(app.eta_tPlot,'minor')
                            xlabel(app.eta_tPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                            legend(app.eta_tPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_pPlot,pi_f,eta_P(:,:),"LineWidth",1.4);
                            title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_pPlot,'minor')
                            xlabel(app.eta_pPlot,"$ \pi_f $","Interpreter","Latex");
                            ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                            legend(app.eta_pPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_thermoPlot,pi_f,eta_T(:,:),"LineWidth",1.4);
                            title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_thermoPlot,'minor')
                            xlabel(app.eta_thermoPlot,"$ \pi_f $","Interpreter","Latex");
                            ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                            legend(app.eta_thermoPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_oPlot,pi_f,eta_O(:,:),"LineWidth",1.4);
                            title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_oPlot,'minor')
                            xlabel(app.eta_oPlot,"$ \pi_f $","Interpreter","Latex");
                            ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                            legend(app.eta_oPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                            
                        case 'alpha'
                            plot(app.FovermdotPlot,alpha,F__mdot_0(:,:),"LineWidth",1.4)
                            title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0} Graph$","Interpreter","Latex")
                            grid(app.FovermdotPlot,'minor')
                            xlabel(app.FovermdotPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                            legend(app.FovermdotPlot,'\pi_f='+string(pi_f),'Location','southeast','NumColumns',2);
                            
                            plot(app.SPlot,alpha,S(:,:),"LineWidth",1.4)
                            title(app.SPlot,"S $Graph$","Interpreter","Latex")
                            grid(app.SPlot,'minor')
                            xlabel(app.SPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                            legend(app.SPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                            
                            plot(app.FuelPlot,alpha,f(:,:),alpha,f_AB(:,:),alpha,f_DB(:,:),"LineWidth",1.4);
                            title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                            grid(app.FuelPlot,'minor')
                            xlabel(app.FuelPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                            leg = ['f for \pi_f='+string(pi_f),'f_AB for \pi_f='+string(pi_f),'f_DB for \pi_f='+string(pi_f)];
                            legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_cPlot,alpha,eta_c(:,:),"LineWidth",1.4)
                            title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                            grid(app.eta_cPlot,'minor')
                            xlabel(app.eta_cPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                            legend(app.eta_cPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_tPlot,alpha,eta_t(:,:),"LineWidth",1.4)
                            title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                            grid(app.eta_tPlot,'minor')
                            xlabel(app.eta_tPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                            legend(app.eta_tPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_pPlot,alpha,eta_P(:,:),"LineWidth",1.4);
                            title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_pPlot,'minor')
                            xlabel(app.eta_pPlot,"$ \alpha $","Interpreter","Latex");
                            ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                            legend(app.eta_pPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_thermoPlot,alpha,eta_T(:,:),"LineWidth",1.4);
                            title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_thermoPlot,'minor')
                            xlabel(app.eta_thermoPlot,"$ \alpha $","Interpreter","Latex");
                            ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                            legend(app.eta_thermoPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_oPlot,alpha,eta_O(:,:),"LineWidth",1.4);
                            title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_oPlot,'minor')
                            xlabel(app.eta_oPlot,"$ \alpha $","Interpreter","Latex");
                            ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                            legend(app.eta_oPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                            
                    end
                case 'Varying pi_f and pi_c'
                    alpha = alpha_min;
                    M_0 = M_0_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    pi_f = pi_f_min:pi_f_increment:pi_f_max;
                    pi_c = pi_c_min:pi_c_increment:pi_c_max;
                    tablo = zeros(length(pi_f)*length(pi_c),18);
                    
                    
                    for i = 1:length(pi_f)
                        for j=1:length(pi_c)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0,T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7,T_t17,pi_c(j),pi_f(i),alpha);
                            
                            tablo(length(pi_c)*(i-1)+j,:) = ...
                                [M_0,pi_c(j),pi_f(i),alpha,T_t4,T_t7,T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    switch xaxisval
                        case 'pi_c'
                            % Handling Plots
                            
                            plot(app.FovermdotPlot,pi_c,F__mdot_0(:,:),"LineWidth",1.4)
                            title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                            grid(app.FovermdotPlot,'minor')
                            xlabel(app.FovermdotPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                            legend(app.FovermdotPlot,'\pi_f='+string(pi_f),'Location','southeast','NumColumns',2);
                            
                            plot(app.SPlot,pi_c,S(:,:),"LineWidth",1.4)
                            title(app.SPlot,"S $Graph$","Interpreter","Latex")
                            grid(app.SPlot,'minor')
                            xlabel(app.SPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                            legend(app.SPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                            
                            p = plot(app.FuelPlot,pi_c,f(1,:),pi_c,f_AB(:,:),pi_c,f_DB(:,:),"LineWidth",1.4);
                            title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                            grid(app.FuelPlot,'minor')
                            xlabel(app.FuelPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                            leg = ['f for all \pi_f','f_{AB} for \pi_f='+string(pi_f),'f_{DB} for \pi_f='+string(pi_f)];
                            legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_cPlot,pi_c,eta_c(:,:),"LineWidth",1.4)
                            title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                            grid(app.eta_cPlot,'minor')
                            xlabel(app.eta_cPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                            legend(app.eta_cPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_tPlot,pi_c,eta_t(:,:),"LineWidth",1.4)
                            title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                            grid(app.eta_tPlot,'minor')
                            xlabel(app.eta_tPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                            legend(app.eta_tPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_pPlot,pi_c,eta_P(:,:),"LineWidth",1.4);
                            title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_pPlot,'minor')
                            xlabel(app.eta_pPlot,"$ \pi_c $","Interpreter","Latex");
                            ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                            legend(app.eta_pPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_thermoPlot,pi_c,eta_T(:,:),"LineWidth",1.4);
                            title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_thermoPlot,'minor')
                            xlabel(app.eta_thermoPlot,"$ \pi_c $","Interpreter","Latex");
                            ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                            legend(app.eta_thermoPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_oPlot,pi_c,eta_O(:,:),"LineWidth",1.4);
                            title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_oPlot,'minor')
                            xlabel(app.eta_oPlot,"$ \pi_c $","Interpreter","Latex");
                            ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                            legend(app.eta_oPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                            
                        case 'pi_f'
                            plot(app.FovermdotPlot,pi_f,F__mdot_0(:,:),"LineWidth",1.4)
                            title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0} Graph$","Interpreter","Latex")
                            grid(app.FovermdotPlot,'minor')
                            xlabel(app.FovermdotPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                            legend(app.FovermdotPlot,'\pi_c='+string(pi_c),'Location','southeast','NumColumns',2);
                            
                            plot(app.SPlot,pi_f,S(:,:),"LineWidth",1.4)
                            title(app.SPlot,"S $Graph$","Interpreter","Latex")
                            grid(app.SPlot,'minor')
                            xlabel(app.SPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                            legend(app.SPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                            
                            plot(app.FuelPlot,pi_f,f(:,1),pi_f,f_AB(:,:),pi_f,f_DB(:,1),"LineWidth",1.4);
                            title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                            grid(app.FuelPlot,'minor')
                            xlabel(app.FuelPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                            leg = ['f for  \pi_c='+string(pi_c),'f_AB for \pi_c='+string(pi_c),'f_DB for \pi_c='+string(pi_c)];
                            legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_cPlot,pi_f,eta_c(:,:),"LineWidth",1.4)
                            title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                            grid(app.eta_cPlot,'minor')
                            xlabel(app.eta_cPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                            legend(app.eta_cPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_tPlot,pi_f,eta_t(:,:),"LineWidth",1.4)
                            title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                            grid(app.eta_tPlot,'minor')
                            xlabel(app.eta_tPlot,"$\pi_f$","Interpreter","Latex");
                            ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                            legend(app.eta_tPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_pPlot,pi_f,eta_P(:,:),"LineWidth",1.4);
                            title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_pPlot,'minor')
                            xlabel(app.eta_pPlot,"$ \pi_f $","Interpreter","Latex");
                            ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                            legend(app.eta_pPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_thermoPlot,pi_f,eta_T(:,:),"LineWidth",1.4);
                            title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_thermoPlot,'minor')
                            xlabel(app.eta_thermoPlot,"$ \pi_f $","Interpreter","Latex");
                            ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                            legend(app.eta_thermoPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_oPlot,pi_f,eta_O(:,:),"LineWidth",1.4);
                            title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_oPlot,'minor')
                            xlabel(app.eta_oPlot,"$ \pi_f $","Interpreter","Latex");
                            ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                            legend(app.eta_oPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                    end
                    
                case 'Varying Bypass and pi_c'
                    alpha = alpha_min:alpha_increment:alpha_max;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    M_0 = M_0_min;
                    pi_f = pi_f_min;
                    pi_c = pi_c_min:pi_c_increment:pi_c_max;
                    tablo = zeros(length(alpha)*length(pi_c),18);
                    
                    for i = 1:length(pi_c)
                        for j=1:length(alpha)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0,T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7,T_t17,pi_c(i),pi_f,alpha(j));
                            
                            tablo(length(alpha)*(i-1)+j,:) = ...
                                [M_0,pi_c(i),pi_f,alpha(j),T_t4,T_t7,T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end


                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    switch xaxisval
                        
                        
                        case 'pi_c'
                            % Handling Plots
                            
                            plot(app.FovermdotPlot,pi_c,F__mdot_0(:,:),"LineWidth",1.4)
                            title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                            grid(app.FovermdotPlot,'minor')
                            xlabel(app.FovermdotPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                            legend(app.FovermdotPlot,'\alpha='+string(alpha),'Location','southeast','NumColumns',2);
                            
                            plot(app.SPlot,pi_c,S(:,:),"LineWidth",1.4)
                            title(app.SPlot,"S $Graph$","Interpreter","Latex")
                            grid(app.SPlot,'minor')
                            xlabel(app.SPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                            legend(app.SPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                            
                            p = plot(app.FuelPlot,pi_c,f(:,:),pi_c,f_AB(:,:),pi_c,f_DB(:,:),"LineWidth",1.4);
                            title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                            grid(app.FuelPlot,'minor')
                            xlabel(app.FuelPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                            leg = ['f for \alpha='+string(alpha),'f_{AB} for \alpha='+string(alpha),'f_{DB} for \alpha='+string(alpha)];
                            legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_cPlot,pi_c,eta_c(:,:),"LineWidth",1.4)
                            title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                            grid(app.eta_cPlot,'minor')
                            xlabel(app.eta_cPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                            legend(app.eta_cPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_tPlot,pi_c,eta_t(:,:),"LineWidth",1.4)
                            title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                            grid(app.eta_tPlot,'minor')
                            xlabel(app.eta_tPlot,"$\pi_c$","Interpreter","Latex");
                            ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                            legend(app.eta_tPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_pPlot,pi_c,eta_P(:,:),"LineWidth",1.4);
                            title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_pPlot,'minor')
                            xlabel(app.eta_pPlot,"$ \pi_c $","Interpreter","Latex");
                            ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                            legend(app.eta_pPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_thermoPlot,pi_c,eta_T(:,:),"LineWidth",1.4);
                            title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_thermoPlot,'minor')
                            xlabel(app.eta_thermoPlot,"$ \pi_c $","Interpreter","Latex");
                            ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                            legend(app.eta_thermoPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_oPlot,pi_c,eta_O(:,:),"LineWidth",1.4);
                            title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_oPlot,'minor')
                            xlabel(app.eta_oPlot,"$ \pi_c $","Interpreter","Latex");
                            ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                            legend(app.eta_oPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                            
                        case 'alpha'
                            plot(app.FovermdotPlot,alpha,F__mdot_0(:,:),"LineWidth",1.4)
                            title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0} Graph$","Interpreter","Latex")
                            grid(app.FovermdotPlot,'minor')
                            xlabel(app.FovermdotPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                            legend(app.FovermdotPlot,'\pi_c='+string(pi_c),'Location','southeast','NumColumns',2);
                            
                            plot(app.SPlot,alpha,S(:,:),"LineWidth",1.4)
                            title(app.SPlot,"S $Graph$","Interpreter","Latex")
                            grid(app.SPlot,'minor')
                            xlabel(app.SPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                            legend(app.SPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                            
                            plot(app.FuelPlot,alpha,f(:,:),alpha,f_AB(:,:),alpha,f_DB(:,:),"LineWidth",1.4);
                            title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                            grid(app.FuelPlot,'minor')
                            xlabel(app.FuelPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                            leg = ['f for \pi_c='+string(pi_c),'f_AB for \pi_c='+string(pi_c),'f_DB for \pi_c='+string(pi_c)];
                            legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_cPlot,alpha,eta_c(:,:),"LineWidth",1.4)
                            title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                            grid(app.eta_cPlot,'minor')
                            xlabel(app.eta_cPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                            legend(app.eta_cPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_tPlot,alpha,eta_t(:,:),"LineWidth",1.4)
                            title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                            grid(app.eta_tPlot,'minor')
                            xlabel(app.eta_tPlot,"$\alpha$","Interpreter","Latex");
                            ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                            legend(app.eta_tPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                            
                            plot(app.eta_pPlot,alpha,eta_P(:,:),"LineWidth",1.4);
                            title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_pPlot,'minor')
                            xlabel(app.eta_pPlot,"$ \alpha $","Interpreter","Latex");
                            ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                            legend(app.eta_pPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_thermoPlot,alpha,eta_T(:,:),"LineWidth",1.4);
                            title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_thermoPlot,'minor')
                            xlabel(app.eta_thermoPlot,"$ \alpha $","Interpreter","Latex");
                            ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                            legend(app.eta_thermoPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                            
                            plot(app.eta_oPlot,alpha,eta_O(:,:),"LineWidth",1.4);
                            title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                            grid(app.eta_oPlot,'minor')
                            xlabel(app.eta_oPlot,"$ \alpha $","Interpreter","Latex");
                            ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                            legend(app.eta_oPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                    end
                    
                case 'Varying Mach No and pi_c'
                    alpha = alpha_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    M_0 = M_0_min:M_0_increment:M_0_max;
                    pi_f = pi_f_min;
                    pi_c = pi_c_min:pi_c_increment:pi_c_max;
                    tablo = zeros(length(M_0)*length(pi_c),18);
                    
                    for i = 1:length(M_0)
                        for j=1:length(pi_c)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0(i),T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7,T_t17,pi_c(j),pi_f,alpha);
                            
                            tablo(length(pi_c)*(i-1)+j,:) = ...
                                [M_0(i),pi_c(j),pi_f,alpha,T_t4,T_t7,T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    
                    
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    % Handling Plots
                    plot(app.FovermdotPlot,M_0,F__mdot_0(:,:),"LineWidth",1.4)
                    title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                    grid(app.FovermdotPlot,'minor')
                    xlabel(app.FovermdotPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                    legend(app.FovermdotPlot,'\pi_c='+string(pi_c),'Location','southeast','NumColumns',2);
                    
                    plot(app.SPlot,M_0,S(:,:),"LineWidth",1.4)
                    title(app.SPlot,"S $Graph$","Interpreter","Latex")
                    grid(app.SPlot,'minor')
                    xlabel(app.SPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                    legend(app.SPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                    
                    plot(app.FuelPlot,M_0,f(:,:),M_0,f_AB(:,:),M_0,f_DB(:,:),"LineWidth",1.4);
                    title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                    grid(app.FuelPlot,'minor')
                    xlabel(app.FuelPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                    leg = ['f for \pi_c='+string(pi_c),'f_{AB} for \pi_c='+string(pi_c),'f_{DB} for \pi_c='+string(pi_c)];
                    legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_cPlot,M_0,eta_c(:,:),"LineWidth",1.4)
                    title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                    grid(app.eta_cPlot,'minor')
                    xlabel(app.eta_cPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                    legend(app.eta_cPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_tPlot,M_0,eta_t(:,:),"LineWidth",1.4)
                    title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                    grid(app.eta_tPlot,'minor')
                    xlabel(app.eta_tPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                    legend(app.eta_tPlot,'\pi_c='+string(pi_c),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_pPlot,M_0,eta_P(:,:),"LineWidth",1.4);
                    title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_pPlot,'minor')
                    xlabel(app.eta_pPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                    legend(app.eta_pPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_thermoPlot,M_0,eta_T(:,:),"LineWidth",1.4);
                    title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_thermoPlot,'minor')
                    xlabel(app.eta_thermoPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                    legend(app.eta_thermoPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_oPlot,M_0,eta_O(:,:),"LineWidth",1.4);
                    title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_oPlot,'minor')
                    xlabel(app.eta_oPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                    legend(app.eta_oPlot,'\pi_c='+string(pi_c),'Location','northwest','NumColumns',2);
                    
                case 'Varying Mach No and Bypass'
                    alpha = alpha_min:alpha_increment:alpha_max;
                    M_0 = M_0_min:M_0_increment:M_0_max;
                    pi_f = pi_f_min;
                    pi_c = pi_c_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    tablo = zeros(length(M_0)*length(alpha),18);
                    
                    for i = 1:length(M_0)
                        for j=1:length(alpha)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0(i),T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7,T_t17,pi_c,pi_f,alpha(j));
                            
                            tablo(length(alpha)*(i-1)+j,:) = ...
                                [M_0(i),pi_c,pi_f,alpha(j),T_t4,T_t7,T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    
                    
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    % Handling Plots
                    plot(app.FovermdotPlot,M_0,F__mdot_0(:,:),"LineWidth",1.4)
                    title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                    grid(app.FovermdotPlot,'minor')
                    xlabel(app.FovermdotPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                    legend(app.FovermdotPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                    
                    plot(app.SPlot,M_0,S(:,:),"LineWidth",1.4)
                    title(app.SPlot,"S $Graph$","Interpreter","Latex")
                    grid(app.SPlot,'minor')
                    xlabel(app.SPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                    legend(app.SPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                    
                    plot(app.FuelPlot,M_0,f(:,1),M_0,f_AB(:,:),M_0,f_DB(:,1),"LineWidth",1.4);
                    title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                    grid(app.FuelPlot,'minor')
                    xlabel(app.FuelPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                    leg = ['f for all \alpha=','f_{AB} for \alpha='+string(alpha),'f_{DB} for all \alpha='];
                    legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_cPlot,M_0,eta_c(:,:),"LineWidth",1.4)
                    title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                    grid(app.eta_cPlot,'minor')
                    xlabel(app.eta_cPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                    legend(app.eta_cPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_tPlot,M_0,eta_t(:,:),"LineWidth",1.4)
                    title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                    grid(app.eta_tPlot,'minor')
                    xlabel(app.eta_tPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                    legend(app.eta_tPlot,'\alpha='+string(alpha),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_pPlot,M_0,eta_P(:,:),"LineWidth",1.4);
                    title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_pPlot,'minor')
                    xlabel(app.eta_pPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                    legend(app.eta_pPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_thermoPlot,M_0,eta_T(:,:),"LineWidth",1.4);
                    title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_thermoPlot,'minor')
                    xlabel(app.eta_thermoPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                    legend(app.eta_thermoPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_oPlot,M_0,eta_O(:,:),"LineWidth",1.4);
                    title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_oPlot,'minor')
                    xlabel(app.eta_oPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                    legend(app.eta_oPlot,'\alpha='+string(alpha),'Location','northwest','NumColumns',2);
                    
                case 'Varying Mach No and pi_f'
                    alpha = alpha_min;
                    M_0 = M_0_min:M_0_increment:M_0_max;
                    pi_f = pi_f_min:pi_f_increment:pi_f_max;
                    pi_c = pi_c_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    tablo = zeros(length(pi_f)*length(M_0),18);
                    
                    for i = 1:length(M_0)
                        for j=1:length(pi_f)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0(i),T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7,T_t17,pi_c,pi_f(j),alpha);
                            
                            tablo(length(pi_f)*(i-1)+j,:) = ...
                                [M_0(i),pi_c,pi_f(j),alpha,T_t4,T_t7,T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    
                    
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    % Handling Plots
                    plot(app.FovermdotPlot,M_0,F__mdot_0(:,:),"LineWidth",1.4);
                    %                     hold(app.FovermdotPlot,'on');
                    %                     plot(app.FovermdotPlot,S(:,:)',F__mdot_0(:,:)',"Marker",'o',"LineWidth",1.4); % degisiklik yapilacak
                    title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                    grid(app.FovermdotPlot,'minor')
                    xlabel(app.FovermdotPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                    legend(app.FovermdotPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                    
                    plot(app.SPlot,M_0,S(:,:),"LineWidth",1.4)
                    title(app.SPlot,"S $Graph$","Interpreter","Latex")
                    grid(app.SPlot,'minor')
                    xlabel(app.SPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                    legend(app.SPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                    
                    plot(app.FuelPlot,M_0,f(:,1),M_0,f_AB(:,:),M_0,f_DB(:,:),"LineWidth",1.4);%%
                    title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                    grid(app.FuelPlot,'minor')
                    xlabel(app.FuelPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                    leg = ['f for all \pi_f','f_{AB} for \pi_f='+string(pi_f),'f_{DB} for \pi_f='+string(pi_f)];
                    legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_cPlot,M_0,eta_c(:,:),"LineWidth",1.4)
                    title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                    grid(app.eta_cPlot,'minor')
                    xlabel(app.eta_cPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                    legend(app.eta_cPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_tPlot,M_0,eta_t(:,:),"LineWidth",1.4)
                    title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                    grid(app.eta_tPlot,'minor')
                    xlabel(app.eta_tPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                    legend(app.eta_tPlot,'\pi_f='+string(pi_f),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_pPlot,M_0,eta_P(:,:),"LineWidth",1.4);
                    title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_pPlot,'minor')
                    xlabel(app.eta_pPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                    legend(app.eta_pPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_thermoPlot,M_0,eta_T(:,:),"LineWidth",1.4);
                    title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_thermoPlot,'minor')
                    xlabel(app.eta_thermoPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                    legend(app.eta_thermoPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_oPlot,M_0,eta_O(:,:),"LineWidth",1.4);
                    title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_oPlot,'minor')
                    xlabel(app.eta_oPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                    legend(app.eta_oPlot,'\pi_f='+string(pi_f),'Location','northwest','NumColumns',2);
                    
                case 'Varying Mach No and T_t4'
                    alpha = alpha_min;
                    M_0 = M_0_min:M_0_increment:M_0_max;
                    pi_f = pi_f_min;
                    pi_c = pi_c_min;
                    T_t4 = T_t4_min:T_t4_increment:T_t4_max;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min;
                    tablo = zeros(length(M_0)*length(T_t4),18);
                    
                    for i = 1:length(M_0)
                        for j=1:length(T_t4)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0(i),T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4(j),T_t7,T_t17,pi_c,pi_f,alpha);
                            
                            tablo(length(T_t4)*(i-1)+j,:) = ...
                                [M_0(i),pi_c,pi_f,alpha,T_t4(j),T_t7,T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    
                    
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    % Handling Plots
                    plot(app.FovermdotPlot,M_0,F__mdot_0(:,:),"LineWidth",1.4);
                    title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                    grid(app.FovermdotPlot,'minor')
                    xlabel(app.FovermdotPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                    legend(app.FovermdotPlot,'T_{t4}='+string(T_t4),'Location','northeast','NumColumns',2);
                    
                    plot(app.SPlot,M_0,S(:,:),"LineWidth",1.4)
                    title(app.SPlot,"S $Graph$","Interpreter","Latex")
                    grid(app.SPlot,'minor')
                    xlabel(app.SPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                    legend(app.SPlot,'T_{t4}='+string(T_t4),'Location','northeast','NumColumns',2);
                    
                    plot(app.FuelPlot,M_0,f(:,:),M_0,f_AB(:,:),M_0,f_DB(:,:),"LineWidth",1.4);%%
                    title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                    grid(app.FuelPlot,'minor')
                    xlabel(app.FuelPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                    leg = ['f for all T_{t4}'+string(T_t4),'f_{AB} for T_{t4}='+string(T_t4),'f_{DB} for T_{t4}='+string(T_t4)];
                    legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_cPlot,M_0,eta_c(:,:),"LineWidth",1.4)
                    title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                    grid(app.eta_cPlot,'minor')
                    xlabel(app.eta_cPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                    legend(app.eta_cPlot,'T_{t4}='+string(T_t4),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_tPlot,M_0,eta_t(:,:),"LineWidth",1.4)
                    title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                    grid(app.eta_tPlot,'minor')
                    xlabel(app.eta_tPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                    legend(app.eta_tPlot,'T_{t4}='+string(T_t4),'Location','northeast','NumColumns',2);

                    plot(app.eta_pPlot,M_0,eta_P(:,:),"LineWidth",1.4);
                    title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_pPlot,'minor')
                    xlabel(app.eta_pPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                    legend(app.eta_pPlot,'T_{t4}='+string(T_t4),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_thermoPlot,M_0,eta_T(:,:),"LineWidth",1.4);
                    title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_thermoPlot,'minor')
                    xlabel(app.eta_thermoPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                    legend(app.eta_thermoPlot,'T_{t4}='+string(T_t4),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_oPlot,M_0,eta_O(:,:),"LineWidth",1.4);
                    title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_oPlot,'minor')
                    xlabel(app.eta_oPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                    legend(app.eta_oPlot,'T_{t4}='+string(T_t4),'Location','northwest','NumColumns',2);
                    
                case 'Varying Mach No and T_t7'
                    alpha = alpha_min;
                    M_0 = M_0_min:M_0_increment:M_0_max;
                    pi_f = pi_f_min;
                    pi_c = pi_c_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min:T_t7_increment:T_t7_max;
                    T_t17 = T_t17_min;
                    tablo = zeros(length(M_0)*length(T_t7),18);
                    
                    for i = 1:length(M_0)
                        for j=1:length(T_t7)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0(i),T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7(j),T_t17,pi_c,pi_f,alpha);
                            
                            tablo(length(T_t7)*(i-1)+j,:) = ...
                                [M_0(i),pi_c,pi_f,alpha,T_t4,T_t7(j),T_t17,...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    
                    
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    % Handling Plots
                    plot(app.FovermdotPlot,M_0,F__mdot_0(:,:),"LineWidth",1.4);
                    title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                    grid(app.FovermdotPlot,'minor')
                    xlabel(app.FovermdotPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                    legend(app.FovermdotPlot,'T_{t7}='+string(T_t7),'Location','northeast','NumColumns',2);
                    
                    plot(app.SPlot,M_0,S(:,:),"LineWidth",1.4)
                    title(app.SPlot,"S $Graph$","Interpreter","Latex")
                    grid(app.SPlot,'minor')
                    xlabel(app.SPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                    legend(app.SPlot,'T_{t7}='+string(T_t7),'Location','northeast','NumColumns',2);
                    
                    plot(app.FuelPlot,M_0,f(:,:),M_0,f_AB(:,:),M_0,f_DB(:,:),"LineWidth",1.4);%%
                    title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                    grid(app.FuelPlot,'minor')
                    xlabel(app.FuelPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                    leg = ['f for all T_{t7}'+string(T_t7),'f_{AB} for T_{t7}='+string(T_t7),'f_{DB} for T_{t7}='+string(T_t7)];
                    legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_cPlot,M_0,eta_c(:,:),"LineWidth",1.4)
                    title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                    grid(app.eta_cPlot,'minor')
                    xlabel(app.eta_cPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                    legend(app.eta_cPlot,'T_{t7}='+string(T_t7),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_tPlot,M_0,eta_t(:,:),"LineWidth",1.4)
                    title(app.eta_tPlot,"$\eta_t$","Interpreter","Latex")
                    grid(app.eta_tPlot,'minor')
                    xlabel(app.eta_tPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_tPlot,"$\eta_t Graph$","Interpreter","Latex");
                    legend(app.eta_tPlot,'T_{t7}='+string(T_t7),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_pPlot,M_0,eta_P(:,:),"LineWidth",1.4);
                    title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_pPlot,'minor')
                    xlabel(app.eta_pPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                    legend(app.eta_pPlot,'T_{t7}='+string(T_t7),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_thermoPlot,M_0,eta_T(:,:),"LineWidth",1.4);
                    title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_thermoPlot,'minor')
                    xlabel(app.eta_thermoPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                    legend(app.eta_thermoPlot,'T_{t7}='+string(T_t7),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_oPlot,M_0,eta_O(:,:),"LineWidth",1.4);
                    title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_oPlot,'minor')
                    xlabel(app.eta_oPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                    legend(app.eta_oPlot,'T_{t7}='+string(T_t7),'Location','northwest','NumColumns',2);
                    
                case 'Varying Mach No and T_t17'
                    alpha = alpha_min;
                    M_0 = M_0_min:M_0_increment:M_0_max;
                    pi_f = pi_f_min;
                    pi_c = pi_c_min;
                    T_t4 = T_t4_min;
                    T_t7 = T_t7_min;
                    T_t17 = T_t17_min:T_t17_increment:T_t17_max;
                    tablo = zeros(length(M_0)*length(T_t7),18);
                    
                    for i = 1:length(M_0)
                        for j=1:length(T_t17)
                            [F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)] = ...
                                ATFwithSeperateExhausts(app,M_0(i),T_0,gamma_c,c_pc,gamma_t,c_pt,h_PR,...
                                gamma_AB,c_p_AB,gamma_DB,c_p_DB,pi_d_max,pi_b,pi_AB,pi_DB,pi_n,...
                                pi_fn,e_c,e_f,e_t,eta_b,eta_AB,eta_DB,eta_m,P_0__P_9,P_0__P_19,...
                                T_t4,T_t7,T_t17(j),pi_c,pi_f,alpha);
                            
                            tablo(length(T_t17)*(i-1)+j,:) = ...
                                [M_0(i),pi_c,pi_f,alpha,T_t4,T_t7,T_t17(j),...
                                F__mdot_0(i,j),f(i,j),f_AB(i,j),f_DB(i,j),S(i,j),eta_c(i,j),eta_t(i,j),eta_f(i,j),eta_P(i,j),eta_T(i,j),eta_O(i,j)];
                            
                        end
                    end
                    
                    
                    % Creating Table
                    t = table(tablo(:,1),tablo(:,2),tablo(:,3),tablo(:,4),tablo(:,5),tablo(:,6),tablo(:,7),...
                        tablo(:,8),tablo(:,9),tablo(:,10),tablo(:,11),tablo(:,12),tablo(:,13),tablo(:,14),...
                        tablo(:,15),tablo(:,16),tablo(:,17),tablo(:,18));
                    app.UITable.Data = t;
                    vars = {'M_0','pi_c','pi_f','alpha','T_t4','T_t7','T_t17','F__mdot_0','f','f_AB','f_DB','S','eta_c','eta_t','eta_f','eta_P','eta_T','eta_O'};
                    app.UITable.ColumnName = vars;
                    
                    % Handling Plots
                    
                    plot(app.FovermdotPlot,M_0,F__mdot_0(:,:),"LineWidth",1.4);
                    title(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ Graph$","Interpreter","Latex")
                    grid(app.FovermdotPlot,'minor')
                    xlabel(app.FovermdotPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FovermdotPlot,"$\frac{F}{\dot{m}_0}\ [lbf/(lbm/sec)]$","Interpreter","Latex");
                    legend(app.FovermdotPlot,'T_{t17}='+string(T_t17),'Location','northeast','NumColumns',2);
                    set(app.FovermdotPlot,'Colormap',[1,1,1]);
                    plot(app.SPlot,M_0,S(:,:),"LineWidth",1.4)
                    title(app.SPlot,"S $Graph$","Interpreter","Latex")
                    grid(app.SPlot,'minor')
                    xlabel(app.SPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.SPlot,"$S\ [(lbm/hr)/lbf]$","Interpreter","Latex");
                    legend(app.SPlot,'T_{t17}='+string(T_t17),'Location','northeast','NumColumns',2);
                    
                    plot(app.FuelPlot,M_0,f(:,:),M_0,f_AB(:,:),M_0,f_DB(:,:),"LineWidth",1.4);%%
                    title(app.FuelPlot,"Fuel $Graph$","Interpreter","Latex")
                    grid(app.FuelPlot,'minor')
                    xlabel(app.FuelPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.FuelPlot,"$f,f_{AB},f_{DB}$","Interpreter","Latex");
                    leg = ['f for all T_{t17}'+string(T_t17),'f_{AB} for T_{t17}='+string(T_t17),'f_{DB} for T_{t17}='+string(T_t17)];
                    legend(app.FuelPlot,leg,'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_cPlot,M_0,eta_c(:,:),"LineWidth",1.4)
                    title(app.eta_cPlot,"$\eta_c$ $Graph$","Interpreter","Latex")
                    grid(app.eta_cPlot,'minor')
                    xlabel(app.eta_cPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_cPlot,"$\eta_c$","Interpreter","Latex");
                    legend(app.eta_cPlot,'T_{t17}='+string(T_t17),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_tPlot,M_0,eta_t(:,:),"LineWidth",1.4)
                    title(app.eta_tPlot,"$\eta_t \ Graph$","Interpreter","Latex")
                    grid(app.eta_tPlot,'minor')
                    xlabel(app.eta_tPlot,"$M_0$","Interpreter","Latex");
                    ylabel(app.eta_tPlot,"$\eta_t$","Interpreter","Latex");
                    legend(app.eta_tPlot,'T_{t17}='+string(T_t17),'Location','northeast','NumColumns',2);
                    
                    plot(app.eta_pPlot,M_0,eta_P(:,:),"LineWidth",1.4);
                    title(app.eta_pPlot," Propulsive Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_pPlot,'minor')
                    xlabel(app.eta_pPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_pPlot,"$\eta_P$","Interpreter","Latex");
                    legend(app.eta_pPlot,'T_{t17}='+string(T_t17),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_thermoPlot,M_0,eta_T(:,:),"LineWidth",1.4);
                    title(app.eta_thermoPlot,"Thermal Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_thermoPlot,'minor')
                    xlabel(app.eta_thermoPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_thermoPlot,"$\eta_T$","Interpreter","Latex");
                    legend(app.eta_thermoPlot,'T_{t17}='+string(T_t17),'Location','northwest','NumColumns',2);
                    
                    plot(app.eta_oPlot,M_0,eta_O(:,:),"LineWidth",1.4);
                    title(app.eta_oPlot,"Efficiency $Graph$","Interpreter","Latex")
                    grid(app.eta_oPlot,'minor')
                    xlabel(app.eta_oPlot,"$ M_0 $","Interpreter","Latex");
                    ylabel(app.eta_oPlot,"$\eta_O$","Interpreter","Latex");
                    legend(app.eta_oPlot,'T_{t17}='+string(T_t17),'Location','northwest','NumColumns',2);
            end
            
        end

        % Value changed function: CaseDropDown
        function CaseDropDownValueChanged(app, event)
            value = app.CaseDropDown.Value;
            
            switch value
                case 'Single Condition'
                    set(app.MachNoIncrementEditField,'enable','off');
                    set(app.MaxMachNumberEditField,'enable','off');
                    set(app.pi_cIncrementEditField,'enable','off');
                    set(app.Maxpi_cValueEditField,'enable','off');
                    set(app.pi_fIncrementEditField,'enable','off');
                    set(app.Maxpi_fEditField,'enable','off');
                    set(app.ByPassRatioIncrementEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','off');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    app.MachNumberEditFieldLabel.Text = 'Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'pi_f';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'pi_c';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    set(app.XAxisSwitch,'enable','off');
                    
                case 'Varying Bypass and pi_f'
                    set(app.MachNoIncrementEditField,'enable','off');
                    set(app.MaxMachNumberEditField,'enable','off');
                    set(app.pi_cIncrementEditField,'enable','off');
                    set(app.Maxpi_cValueEditField,'enable','off');
                    set(app.pi_fIncrementEditField,'enable','on');
                    set(app.Maxpi_fEditField,'enable','on');
                    set(app.ByPassRatioIncrementEditField,'enable','on');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','on');
                    set(app.XAxisSwitch,'enable','on');
                    app.MachNumberEditFieldLabel.Text = 'Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'Min pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'Min By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'pi_c';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'alpha','pi_f'};
                    
                case 'Varying pi_f and pi_c'
                    set(app.MachNoIncrementEditField,'enable','off');
                    set(app.MaxMachNumberEditField,'enable','off');
                    set(app.pi_cIncrementEditField,'enable','on');
                    set(app.Maxpi_cValueEditField,'enable','on');
                    set(app.pi_fIncrementEditField,'enable','on');
                    set(app.Maxpi_fEditField,'enable','on');
                    set(app.ByPassRatioIncrementEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','off');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.XAxisSwitch,'enable','on');
                    app.MachNumberEditFieldLabel.Text = 'Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'Min pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'Min pi_c Value';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'pi_f','pi_c'};
                case 'Varying Bypass and pi_c'
                    
                    set(app.MachNoIncrementEditField,'enable','off');
                    set(app.MaxMachNumberEditField,'enable','off');
                    set(app.pi_cIncrementEditField,'enable','on');
                    set(app.Maxpi_cValueEditField,'enable','on');
                    set(app.pi_fIncrementEditField,'enable','off');
                    set(app.Maxpi_fEditField,'enable','off');
                    set(app.ByPassRatioIncrementEditField,'enable','on');
                    set(app.MaxByPassRatioEditField,'enable','on');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.XAxisSwitch,'enable','on');
                    app.MachNumberEditFieldLabel.Text = 'Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'Min By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'Min pi_c Value';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'pi_c','alpha'};
                    
                case 'Varying Mach No and pi_c'
                    set(app.MachNoIncrementEditField,'enable','on');
                    set(app.MaxMachNumberEditField,'enable','on');
                    set(app.pi_cIncrementEditField,'enable','on');
                    set(app.Maxpi_cValueEditField,'enable','on');
                    set(app.pi_fIncrementEditField,'enable','off');
                    set(app.Maxpi_fEditField,'enable','off');
                    set(app.ByPassRatioIncrementEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','off');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.XAxisSwitch,'enable','off');
                    app.MachNumberEditFieldLabel.Text = 'Min Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'Min pi_c Value';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'M_0','pi_c'};
                    
                case 'Varying Mach No and Bypass'
                    set(app.MachNoIncrementEditField,'enable','on');
                    set(app.MaxMachNumberEditField,'enable','on');
                    set(app.pi_cIncrementEditField,'enable','off');
                    set(app.Maxpi_cValueEditField,'enable','off');
                    set(app.pi_fIncrementEditField,'enable','off');
                    set(app.Maxpi_fEditField,'enable','off');
                    set(app.ByPassRatioIncrementEditField,'enable','on');
                    set(app.MaxByPassRatioEditField,'enable','on');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.XAxisSwitch,'enable','off');
                    app.MachNumberEditFieldLabel.Text = 'Min Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'Min By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'pi_c Value';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'M_0','alpha'};
                    
                case 'Varying Mach No and pi_f'
                    set(app.MachNoIncrementEditField,'enable','on');
                    set(app.MaxMachNumberEditField,'enable','on');
                    set(app.pi_cIncrementEditField,'enable','off');
                    set(app.Maxpi_cValueEditField,'enable','off');
                    set(app.pi_fIncrementEditField,'enable','on');
                    set(app.Maxpi_fEditField,'enable','on');
                    set(app.ByPassRatioIncrementEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','off');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.XAxisSwitch,'enable','off');
                    app.MachNumberEditFieldLabel.Text = 'Min Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'Min pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'pi_c Value';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'M_0','pi_F'};
                    
                case 'Varying Mach No and T_t4'
                    set(app.MachNoIncrementEditField,'enable','on');
                    set(app.MaxMachNumberEditField,'enable','on');
                    set(app.pi_cIncrementEditField,'enable','off');
                    set(app.Maxpi_cValueEditField,'enable','off');
                    set(app.pi_fIncrementEditField,'enable','off');
                    set(app.Maxpi_fEditField,'enable','off');
                    set(app.ByPassRatioIncrementEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','off');
                    set(app.T_t4IncrementEditField,'enable','on');
                    set(app.MaxT_t4KEditField,'enable','on');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.XAxisSwitch,'enable','off');
                    app.MachNumberEditFieldLabel.Text = 'Min Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'pi_c Value';
                    app.MinT_t4KLabel.Text = 'Min T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'M_0','T_t4'};
                    
                case 'Varying Mach No and T_t7'
                    set(app.MachNoIncrementEditField,'enable','on');
                    set(app.MaxMachNumberEditField,'enable','on');
                    set(app.pi_cIncrementEditField,'enable','off');
                    set(app.Maxpi_cValueEditField,'enable','off');
                    set(app.pi_fIncrementEditField,'enable','off');
                    set(app.Maxpi_fEditField,'enable','off');
                    set(app.ByPassRatioIncrementEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','off');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','on');
                    set(app.MaxT_t7KEditField,'enable','on');
                    set(app.T_t17IncrementEditField,'enable','off');
                    set(app.MaxT_t17KEditField,'enable','off');
                    set(app.XAxisSwitch,'enable','off');
                    app.MachNumberEditFieldLabel.Text = 'Min Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'pi_c Value';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'Min T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'T_t17 (K)';
                    app.XAxisSwitch.Items = {'M_0','T_t7'};
                    
                case 'Varying Mach No and T_t17'
                    set(app.MachNoIncrementEditField,'enable','on');
                    set(app.MaxMachNumberEditField,'enable','on');
                    set(app.pi_cIncrementEditField,'enable','off');
                    set(app.Maxpi_cValueEditField,'enable','off');
                    set(app.pi_fIncrementEditField,'enable','off');
                    set(app.Maxpi_fEditField,'enable','off');
                    set(app.ByPassRatioIncrementEditField,'enable','off');
                    set(app.MaxByPassRatioEditField,'enable','off');
                    set(app.T_t4IncrementEditField,'enable','off');
                    set(app.MaxT_t4KEditField,'enable','off');
                    set(app.T_t7IncrementEditField,'enable','off');
                    set(app.MaxT_t7KEditField,'enable','off');
                    set(app.T_t17IncrementEditField,'enable','on');
                    set(app.MaxT_t17KEditField,'enable','on');
                    set(app.XAxisSwitch,'enable','off');
                    app.MachNumberEditFieldLabel.Text = 'Min Mach Number';
                    app.Minpi_fValueEditFieldLabel.Text = 'pi_f Value';
                    app.MinByPassRatioValueEditFieldLabel.Text = 'By-pass Ratio';
                    app.Minpi_cValueLabel.Text = 'pi_c Value';
                    app.MinT_t4KLabel.Text = 'T_t4 (K)';
                    app.MinT_t7KLabel.Text = 'T_t7 (K)';
                    app.MinT_t17KLabel.Text = 'Min T_t17 (K)';
                    app.XAxisSwitch.Items = {'M_0','T_t17'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AfterburnerTurbofan = uifigure('Visible', 'off');
            app.AfterburnerTurbofan.Name = 'UI Figure';
            app.FlightConditionsPanel = uipanel(app.AfterburnerTurbofan);
            app.FlightConditionsPanel.Title = 'Flight Conditions';
            app.GridLayout = uigridlayout(app.FlightConditionsPanel);
            app.T_0KEditFieldLabel = uilabel(app.GridLayout);
            app.T_0KEditFieldLabel.Text = 'T_0 (K)';
            app.T_0KEditField = uieditfield(app.GridLayout, 'numeric');
            app.T_0KEditField.Value = 216.6667;
            app.MachNumberEditFieldLabel = uilabel(app.GridLayout);
            app.MachNumberEditFieldLabel.Text = 'Mach Number';
            app.MachNumberMinEditField = uieditfield(app.GridLayout, 'numeric');
            app.MachNumberMinEditField.Value = 1.6;
            app.P_0P_9EditFieldLabel = uilabel(app.GridLayout);
            app.P_0P_9EditFieldLabel.Text = 'P_0/P_9';
            app.P_0P_9EditField = uieditfield(app.GridLayout, 'numeric');
            app.P_0P_9EditField.Value = 1;
            app.P_0P_19EditFieldLabel = uilabel(app.GridLayout);
            app.P_0P_19EditFieldLabel.Text = 'P_0/P_19';
            app.P_0P_19EditField = uieditfield(app.GridLayout, 'numeric');
            app.P_0P_19EditField.Value = 1;
            app.gamma_cEditFieldLabel = uilabel(app.GridLayout);
            app.gamma_cEditFieldLabel.Text = 'gamma_c';
            app.gamma_cEditField = uieditfield(app.GridLayout, 'numeric');
            app.gamma_cEditField.Value = 1.4;
            app.c_pckJkgKLabel = uilabel(app.GridLayout);
            app.c_pckJkgKLabel.Text = 'c_pc (kJ/(kg.K))';
            app.c_pckJkgKEditField = uieditfield(app.GridLayout, 'numeric');
            app.c_pckJkgKEditField.Value = 1.0048;
            app.MaxMachNumberEditFieldLabel = uilabel(app.GridLayout);
            app.MaxMachNumberEditFieldLabel.Text = 'Max Mach Number';
            app.MaxMachNumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxMachNumberEditField.Value = 2.5;
            app.MachNoIncrementEditFieldLabel = uilabel(app.GridLayout);
            app.MachNoIncrementEditFieldLabel.Text = 'Mach No Increment';
            app.MachNoIncrementEditField = uieditfield(app.GridLayout, 'numeric');
            app.MachNoIncrementEditField.Value = 0.1;
            app.TabGroup = uitabgroup(app.AfterburnerTurbofan);
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'F/mdot'; 'S'; 'f'; 'f_AB'; 'f_DB'; 'eta_c'; 'eta_t'; 'eta_f'};
            app.UITable.RowName = {};
            app.Fm_0dotTab = uitab(app.TabGroup);
            app.Fm_0dotTab.Title = 'F/m_0dot';
            app.FovermdotPlot = uiaxes(app.Fm_0dotTab);
            title(app.FovermdotPlot, 'F/m_0dot')
            xlabel(app.FovermdotPlot, 'pi_f')
            ylabel(app.FovermdotPlot, 'F/m_0dot')
            app.STab = uitab(app.TabGroup);
            app.STab.Title = 'S';
            app.SPlot = uiaxes(app.STab);
            title(app.SPlot, 'Title')
            xlabel(app.SPlot, 'X')
            ylabel(app.SPlot, 'Y')
            app.FuelAirRatioTab = uitab(app.TabGroup);
            app.FuelAirRatioTab.Title = 'Fuel-Air Ratio';
            app.FuelPlot = uiaxes(app.FuelAirRatioTab);
            title(app.FuelPlot, 'Title')
            xlabel(app.FuelPlot, 'X')
            ylabel(app.FuelPlot, 'Y')
            app.Eta_cTab = uitab(app.TabGroup);
            app.Eta_cTab.Title = 'Eta_c';
            app.eta_cPlot = uiaxes(app.Eta_cTab);
            title(app.eta_cPlot, 'Title')
            xlabel(app.eta_cPlot, 'X')
            ylabel(app.eta_cPlot, 'Y')
            app.Eta_tTab = uitab(app.TabGroup);
            app.Eta_tTab.Title = 'Eta_t';
            app.eta_tPlot = uiaxes(app.Eta_tTab);
            title(app.eta_tPlot, 'Title')
            xlabel(app.eta_tPlot, 'X')
            ylabel(app.eta_tPlot, 'Y')
            app.eta_tPlot.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PropulsiveEfficiencyTab = uitab(app.TabGroup);
            app.PropulsiveEfficiencyTab.Title = 'Propulsive Efficiency';
            app.GridLayout7 = uigridlayout(app.PropulsiveEfficiencyTab);
            app.eta_pPlot = uiaxes(app.GridLayout7);
            title(app.eta_pPlot, 'Title')
            xlabel(app.eta_pPlot, 'X')
            ylabel(app.eta_pPlot, 'Y')
            app.TermalEfficiencyTab = uitab(app.TabGroup);
            app.TermalEfficiencyTab.Title = 'Termal Efficiency';
            app.eta_thermoPlot = uiaxes(app.TermalEfficiencyTab);
            title(app.eta_thermoPlot, 'Title')
            xlabel(app.eta_thermoPlot, 'X')
            ylabel(app.eta_thermoPlot, 'Y')
            app.OverallEfficiencyTab = uitab(app.TabGroup);
            app.OverallEfficiencyTab.Title = 'Overall Efficiency';
            app.eta_oPlot = uiaxes(app.OverallEfficiencyTab);
            title(app.eta_oPlot, 'Title')
            xlabel(app.eta_oPlot, 'X')
            ylabel(app.eta_oPlot, 'Y')
            app.DesignConstraintsPanel = uipanel(app.AfterburnerTurbofan);
            app.DesignConstraintsPanel.Title = 'Design Constraints';
            app.GridLayout2 = uigridlayout(app.DesignConstraintsPanel);
            app.MinT_t4KLabel = uilabel(app.GridLayout2);
            app.MinT_t4KLabel.Text = 'Min T_t4 (K)';
            app.MinT_t4KEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinT_t4KEditField.Value = 1944.4;
            app.MinT_t17KLabel = uilabel(app.GridLayout2);
            app.MinT_t17KLabel.Text = 'Min T_t17 (K)';
            app.MinT_t17KEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinT_t17KEditField.Value = 2222.2;
            app.MinT_t7KLabel = uilabel(app.GridLayout2);
            app.MinT_t7KLabel.Text = 'Min T_t7 (K)';
            app.MinT_t7KEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinT_t7KEditField.Value = 2222.2;
            app.T_t4IncrementEditFieldLabel = uilabel(app.GridLayout2);
            app.T_t4IncrementEditFieldLabel.Text = 'T_t4 Increment';
            app.T_t4IncrementEditField = uieditfield(app.GridLayout2, 'numeric');
            app.T_t4IncrementEditField.Value = 50;
            app.MaxT_t4KEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxT_t4KEditFieldLabel.Text = 'Max T_t4 (K)';
            app.MaxT_t4KEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxT_t4KEditField.Value = 2100;
            app.T_t7IncrementEditFieldLabel = uilabel(app.GridLayout2);
            app.T_t7IncrementEditFieldLabel.Text = 'T_t7 Increment';
            app.T_t7IncrementEditField = uieditfield(app.GridLayout2, 'numeric');
            app.T_t7IncrementEditField.Value = 50;
            app.MaxT_t7KEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxT_t7KEditFieldLabel.Text = 'Max T_t7 (K)';
            app.MaxT_t7KEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxT_t7KEditField.Value = 2350;
            app.T_t17IncrementEditFieldLabel = uilabel(app.GridLayout2);
            app.T_t17IncrementEditFieldLabel.Text = 'T_t17 Increment';
            app.T_t17IncrementEditField = uieditfield(app.GridLayout2, 'numeric');
            app.T_t17IncrementEditField.Value = 50;
            app.MaxT_t17KEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxT_t17KEditFieldLabel.Text = 'Max T_t17 (K)';
            app.MaxT_t17KEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxT_t17KEditField.Value = 2350;
            app.ComponentPerformancesPanel = uipanel(app.AfterburnerTurbofan);
            app.ComponentPerformancesPanel.Title = 'Component Performances';
            app.GridLayout3 = uigridlayout(app.ComponentPerformancesPanel);
            app.pi_bEditFieldLabel = uilabel(app.GridLayout3);
            app.pi_bEditFieldLabel.Text = 'pi_b';
            app.pi_bEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pi_bEditField.Value = 0.98;
            app.eta_ABEditFieldLabel = uilabel(app.GridLayout3);
            app.eta_ABEditFieldLabel.Text = 'eta_AB';
            app.eta_ABEditField = uieditfield(app.GridLayout3, 'numeric');
            app.eta_ABEditField.Value = 0.95;
            app.eta_DBEditFieldLabel = uilabel(app.GridLayout3);
            app.eta_DBEditFieldLabel.Text = 'eta_DB';
            app.eta_DBEditField = uieditfield(app.GridLayout3, 'numeric');
            app.eta_DBEditField.Value = 0.95;
            app.eta_mEditFieldLabel = uilabel(app.GridLayout3);
            app.eta_mEditFieldLabel.Text = 'eta_m';
            app.eta_mEditField = uieditfield(app.GridLayout3, 'numeric');
            app.eta_mEditField.Value = 0.99;
            app.eta_bEditFieldLabel = uilabel(app.GridLayout3);
            app.eta_bEditFieldLabel.Text = 'eta_b';
            app.eta_bEditField = uieditfield(app.GridLayout3, 'numeric');
            app.eta_bEditField.Value = 0.99;
            app.pi_DBEditFieldLabel = uilabel(app.GridLayout3);
            app.pi_DBEditFieldLabel.Text = 'pi_DB';
            app.pi_DBEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pi_DBEditField.Value = 0.94;
            app.pi_d_maxEditFieldLabel = uilabel(app.GridLayout3);
            app.pi_d_maxEditFieldLabel.Text = 'pi_d_max';
            app.pi_d_maxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pi_d_maxEditField.Value = 0.98;
            app.e_cEditFieldLabel = uilabel(app.GridLayout3);
            app.e_cEditFieldLabel.Text = 'e_c';
            app.e_cEditField = uieditfield(app.GridLayout3, 'numeric');
            app.e_cEditField.Value = 0.9;
            app.e_tEditFieldLabel = uilabel(app.GridLayout3);
            app.e_tEditFieldLabel.Text = 'e_t';
            app.e_tEditField = uieditfield(app.GridLayout3, 'numeric');
            app.e_tEditField.Value = 0.91;
            app.e_fEditFieldLabel = uilabel(app.GridLayout3);
            app.e_fEditFieldLabel.Text = 'e_f';
            app.e_fEditField = uieditfield(app.GridLayout3, 'numeric');
            app.e_fEditField.Value = 0.89;
            app.pi_fnEditFieldLabel = uilabel(app.GridLayout3);
            app.pi_fnEditFieldLabel.Text = 'pi_fn';
            app.pi_fnEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pi_fnEditField.Value = 0.98;
            app.pi_nEditFieldLabel = uilabel(app.GridLayout3);
            app.pi_nEditFieldLabel.Text = 'pi_n';
            app.pi_nEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pi_nEditField.Value = 0.98;
            app.pi_ABEditFieldLabel = uilabel(app.GridLayout3);
            app.pi_ABEditFieldLabel.Text = 'pi_AB';
            app.pi_ABEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pi_ABEditField.Value = 0.94;
            app.CaseSelectionPanel = uipanel(app.AfterburnerTurbofan);
            app.CaseSelectionPanel.Title = 'Case Selection';
            app.CaseDropDownLabel = uilabel(app.CaseSelectionPanel);
            app.CaseDropDownLabel.Text = 'Case';
            app.CaseDropDown = uidropdown(app.CaseSelectionPanel);
            app.CaseDropDown.Items = {'Single Condition', 'Varying Bypass and pi_f', 'Varying pi_f and pi_c', 'Varying Bypass and pi_c', 'Varying Mach No and pi_c', 'Varying Mach No and Bypass', 'Varying Mach No and pi_f', 'Varying Mach No and T_t4', 'Varying Mach No and T_t7', 'Varying Mach No and T_t17'};
            app.CaseDropDown.ValueChangedFcn = createCallbackFcn(app, @CaseDropDownValueChanged, true);
            app.CaseDropDown.Value = 'Single Condition';
            app.DesignChoicesPanel = uipanel(app.AfterburnerTurbofan);
            app.DesignChoicesPanel.Title = 'Design Choices';
            app.GridLayout4 = uigridlayout(app.DesignChoicesPanel);
            app.ComputeButton = uibutton(app.GridLayout4, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Minpi_cValueLabel = uilabel(app.GridLayout4);
            app.Minpi_cValueLabel.Text = 'Min pi_c Value';
            app.Minpi_cValueEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Minpi_cValueEditField.Value = 22;
            app.Minpi_fValueEditFieldLabel = uilabel(app.GridLayout4);
            app.Minpi_fValueEditFieldLabel.Text = 'Min pi_f Value';
            app.Minpi_fEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Minpi_fEditField.Value = 2;
            app.Maxpi_fValueEditFieldLabel = uilabel(app.GridLayout4);
            app.Maxpi_fValueEditFieldLabel.Text = 'Max pi_f Value';
            app.Maxpi_fEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Maxpi_fEditField.Value = 5;
            app.MinByPassRatioValueEditFieldLabel = uilabel(app.GridLayout4);
            app.MinByPassRatioValueEditFieldLabel.Text = 'Min By-Pass Ratio Value';
            app.MinByPassRatioEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MinByPassRatioEditField.Value = 0.5;
            app.MaxByPassRatioEditFieldLabel = uilabel(app.GridLayout4);
            app.MaxByPassRatioEditFieldLabel.Text = 'Max By-Pass Ratio';
            app.MaxByPassRatioEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MaxByPassRatioEditField.Value = 3;
            app.pi_fIncrementEditFieldLabel = uilabel(app.GridLayout4);
            app.pi_fIncrementEditFieldLabel.Text = 'pi_f Increment';
            app.pi_fIncrementEditField = uieditfield(app.GridLayout4, 'numeric');
            app.pi_fIncrementEditField.Value = 0.2;
            app.ByPassRatioIncrementEditFieldLabel = uilabel(app.GridLayout4);
            app.ByPassRatioIncrementEditFieldLabel.Text = 'By-Pass Ratio Increment';
            app.ByPassRatioIncrementEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ByPassRatioIncrementEditField.Value = 0.5;
            app.pi_cIncrementEditField_2Label = uilabel(app.GridLayout4);
            app.pi_cIncrementEditField_2Label.Text = 'pi_c Increment';
            app.pi_cIncrementEditField = uieditfield(app.GridLayout4, 'numeric');
            app.pi_cIncrementEditField.Value = 1;
            app.Maxpi_cValueEditField_2Label = uilabel(app.GridLayout4);
            app.Maxpi_cValueEditField_2Label.Text = 'Max pi_c Value';
            app.Maxpi_cValueEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Maxpi_cValueEditField.Value = 30;
            app.XAxisSwitchLabel = uilabel(app.GridLayout4);
            app.XAxisSwitchLabel.Text = 'X Axis';
            app.XAxisSwitch = uiswitch(app.GridLayout4, 'slider');
            app.XAxisSwitch.Items = {'alpha', 'pi_f'};
            app.XAxisSwitch.Value = 'alpha';
            app.GasPropertiesPanel = uipanel(app.AfterburnerTurbofan);
            app.GasPropertiesPanel.Title = 'Gas Properties';
            app.GridLayout5 = uigridlayout(app.GasPropertiesPanel);
            app.c_ptkJkgKEditFieldLabel = uilabel(app.GridLayout5);
            app.c_ptkJkgKEditFieldLabel.Text = 'c_pt (kJ/(kg.K))';
            app.c_ptkJkgKEditField = uieditfield(app.GridLayout5, 'numeric');
            app.c_ptkJkgKEditField.Value = 1.2351;
            app.c_pABkJkgKLabel = uilabel(app.GridLayout5);
            app.c_pABkJkgKLabel.Text = 'c_pAB (kJ/(kg.K))';
            app.c_pABkJkgKEditField = uieditfield(app.GridLayout5, 'numeric');
            app.c_pABkJkgKEditField.Value = 1.2351;
            app.c_pDBkJkgKEditFieldLabel = uilabel(app.GridLayout5);
            app.c_pDBkJkgKEditFieldLabel.Text = 'c_pDB (kJ/(kg.K))';
            app.c_pDBkJkgKEditField = uieditfield(app.GridLayout5, 'numeric');
            app.c_pDBkJkgKEditField.Value = 1.2351;
            app.gamma_ABEditFieldLabel = uilabel(app.GridLayout5);
            app.gamma_ABEditFieldLabel.Text = 'gamma_AB';
            app.gamma_ABEditField = uieditfield(app.GridLayout5, 'numeric');
            app.gamma_ABEditField.Value = 1.3;
            app.gamma_tEditFieldLabel = uilabel(app.GridLayout5);
            app.gamma_tEditFieldLabel.Text = 'gamma_t';
            app.gamma_tEditField = uieditfield(app.GridLayout5, 'numeric');
            app.gamma_tEditField.Value = 1.3;
            app.gamma_DBEditFieldLabel = uilabel(app.GridLayout5);
            app.gamma_DBEditFieldLabel.Text = 'gamma_DB';
            app.gamma_DBEditField = uieditfield(app.GridLayout5, 'numeric');
            app.gamma_DBEditField.Value = 1.3;
            app.FuelPropertiesPanel = uipanel(app.AfterburnerTurbofan);
            app.FuelPropertiesPanel.Title = 'Fuel Properties';
            app.GridLayout6 = uigridlayout(app.FuelPropertiesPanel);
            app.h_PRkJkgEditFieldLabel = uilabel(app.GridLayout6);
            app.h_PRkJkgEditFieldLabel.Text = 'h_PR (kJ/kg)';
            app.h_PRkJkgEditField = uieditfield(app.GridLayout6, 'numeric');
            app.h_PRkJkgEditField.Value = 42798;
            % Show the figure after all components are created
            app.AfterburnerTurbofan.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSATFv2_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AfterburnerTurbofan)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AfterburnerTurbofan)
        end
    end
end